classdef start_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NEditFieldLabel          matlab.ui.control.Label
        NEditField               matlab.ui.control.NumericEditField
        UIAxes2                  matlab.ui.control.UIAxes
        RenderButton             matlab.ui.control.Button
        RenderModeDropDownLabel  matlab.ui.control.Label
        RenderModeDropDown       matlab.ui.control.DropDown
        ComputerVisionChallengeG06Label  matlab.ui.control.Label
        StartEditFieldLabel      matlab.ui.control.Label
        StartEditField           matlab.ui.control.NumericEditField
        LDropDownLabel           matlab.ui.control.Label
        LDropDown                matlab.ui.control.DropDown
        RDropDownLabel           matlab.ui.control.Label
        RDropDown                matlab.ui.control.DropDown
        selectButton_Src         matlab.ui.control.Button
        selectButton_BgPath      matlab.ui.control.Button
        BgModeDropDownLabel      matlab.ui.control.Label
        BgModeDropDown           matlab.ui.control.DropDown
        SourceTextAreaLabel      matlab.ui.control.Label
        SourceTextArea           matlab.ui.control.TextArea
        BgPathTextAreaLabel      matlab.ui.control.Label
        BgPathTextArea           matlab.ui.control.TextArea
        Button                   matlab.ui.control.Button
        selectButton_savepath    matlab.ui.control.Button
        SavePathTextAreaLabel    matlab.ui.control.Label
        SavePathTextArea         matlab.ui.control.TextArea
        SaveButton               matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        UIAxes1                  matlab.ui.control.UIAxes
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = public)
        src           % source path, relative or absolute
        L             % choose 1 or 2 to set as left camera
        R             % choose 2 or 3 to set as right camera
        startFrame    % (optional) select the start frame, default=0
        N             % (optional) number of frames to be loaded after the start frame, default=1
        BgPath        % path of background image or video
        renderMode    % the way to render the image
        dest          % path to save the output.avi
        BgMode        % choice between 'image background' or 'video background'
        store         % if the movie be stored
        VideoLength   % the length of the original video
        bg            % background cell
        currentFrame  % current frame of the video output.avi
        want2stop     % if set it to true, the video play will stop
        OriFramePath  % folder to store the portion of the original video frames that was read
        targetFolderMovie % folder to save the rendered frames 
        FramePath     % path for background video frames
        savePath      % folder to save oriVid.avi
        vRender       % struct variable to save the processed video
        vOri          % struct variable to save the original video
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, src, L, R, N, startFrame)
            app.src = '/Users/huang/Desktop/TUM/2020SS/Computer vision/P1E_S2';
            app.N = 4;
            app.L = 1;
            app.R = 2;
            app.startFrame = 200;
            app.dest = 'output.avi';
            app.BgMode = 'image background';
            app.store = true;
            app.renderMode = 'foreground';
            app.dest = 'output.avi';
            app.currentFrame = 0;
            app.want2stop = true;
            app.targetFolderMovie = './results/Movie/';
            app.OriFramePath = './results/OriVidFrame/';
            
            
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            app.N = value;
            if app.N~=4
                msgbox('Please set N to 4 or the segmentation will not be optimal.','Not good N','warn');
            end
        end

        % Callback function
        function LDropDownValueChanged(app, event)
            % choice L between 1 and 2
            value = app.LDropDown.Value;
            app.L = value;
        end

        % Callback function
        function RDropDownValueChanged(app, event)
            % choice R between 2 and 3
            value = app.RDropDown.Value;
            app.R = value;
        end

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)
           % with run of this function the progressed video frames will be save to the folder 'Movie' 
           % and the proportion of the original video frames that read will be
           % save to the folder 'oriVidFrame'
            d = uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Creating the folder to save movie...','Cancelable','on','Indeterminate','on');
            pause(.5);
            % Initialtion
            indexResultFrame = 0;
            numBgFrame = length(app.bg);
            loop = 0;
            ir = ImageReader(app.src, app.L, app.R, app.startFrame,app.N);
            global adaptiveThreshold 
            global count
            adaptiveThreshold = 0.85;
            count = 0;
            % Create folder to store the processed frames,if the folder
            % already exist,remove it and make a new one.
            
            if ~exist(app.targetFolderMovie, 'dir')
                mkdir(app.targetFolderMovie);
            elseif exist(app.targetFolderMovie, 'dir')
                rmdir(app.targetFolderMovie,"s");
                mkdir(app.targetFolderMovie);
            end
            % Create a folder to store the portion of the original video
            % frames that was read,if the folder already exist,remove it 
            % and make a new one.
            
            if ~exist(app.OriFramePath, 'dir')
                mkdir(app.OriFramePath);
            elseif exist(app.OriFramePath, 'dir')
                rmdir(app.OriFramePath,"s");
                mkdir(app.OriFramePath);
            end
            
            d.Message = 'Rendering...';
            
            
            while loop ~= 1
                % Get next tensor
                [left, right, loop] = ir.next();
                numFrame = size(left, 3)/3;  % number of frames in a tensor
                % Generate the same binary mask for all frames in current tensor
                % Case 1: only 1 frame on hand
                if numFrame == 1
                    % Use the information from previous frames
                    ir_end = ImageReader(app.src, app.L, app.R, ir.videoLength-app.N+1, app.N);
                    [left_end, right_end, loop_end] = ir_end.next();
                    binaryMask = segmentation(left_end, right_end);
                else
                    % Case 2: at least 2 frames remaining
                    binaryMask = segmentation(left, right);
                end
                movie = cell(numFrame,1);
                OriFrame = cell(numFrame,1);
                
                % Render and save the resulting frames for current tensor
                for i = 1 : numFrame
                    currentLeft = left(:, :, 3*i-2:3*i);
                    currentMask = binaryMask;
                    indexResultFrame = indexResultFrame + 1;
                    
                    if strcmp(app.renderMode, 'substitute')
                        % Decide the index of background to be applied
                        if numBgFrame == 1  % still image
                            numBg = 1;
                        else  % video
                            % loop video background
                            if indexResultFrame > numBgFrame
                                if mod(indexResultFrame, numBgFrame) == 0
                                    numBg = numBgFrame;
                                else
                                    numBg = mod(indexResultFrame, numBgFrame);
                                end
                            else
                                numBg = indexResultFrame;
                            end
                        end
                        % Subsitute video background
                        movie{i} = render(currentLeft, currentMask, app.bg{numBg}, app.renderMode);
                        OriFrame{i} = currentLeft;
                    else
                        % "foreground"/"background"/"overlay" mode
                        movie{i} = render(currentLeft, currentMask, app.bg, app.renderMode);
                        OriFrame{i} = currentLeft;
                    end
                    % Export the resulting images in target folder
                    imwrite(movie{i}./255, [app.targetFolderMovie, 'movie_', ...
                        num2str(indexResultFrame-1, '%08d'), '.jpg']);
                    imwrite(OriFrame{i}./255, [app.OriFramePath, 'oriVid_', ...
                        num2str(indexResultFrame-1, '%08d'), '.jpg']);
                end
                
%                 movie = cellfun(@(x) x./255, movie, 'UniformOutput', false);
            end
            if exist(app.FramePath,'dir')
                rmdir(app.FramePath,"s");
            end
            d.Message = 'finished';
            pause(1.5);
            close(d);
            msgbox('____Save and Play!____','Render finished!');
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            % choice the start frame between 0 and video length
            value = app.StartEditField.Value;
            if value < app.VideoLength && value >= 0
                app.startFrame = value;
            else
                msgbox('Please set a valid start frame.','false start frame','error');
            end
            
        end

        % Button pushed function: selectButton_Src
        function selectButton_SrcPushed(app, event)
            % select the path of the original video frames that should be
            % processed
            %addpath(fullfile('lib'));
            value = uigetdir();
            app.src = value;
            app.SourceTextArea.Value = app.src;
            ir = ImageReader(app.src, app.L, app.R, app.startFrame,app.N);
            app.VideoLength = ir.videoLength;
        end

        % Button pushed function: selectButton_BgPath
        function selectButton_BgPathPushed(app, event)
            % select the path of the backgroung 
            % the background can be image or video
            ir = ImageReader(app.src, app.L, app.R, app.startFrame,app.N);
            if strcmp(app.BgMode,'video background')
                % if the background is video, it will be splited to frames
                % and resized then save it to the cell variable 'bg'
                [file,src_bg] = uigetfile('*.mp4','*.avi','Select an Background Image');
                value = [src_bg,file];
                app.FramePath = [src_bg,'background_frame'];
                if ~exist(app.FramePath, 'dir')
                    mkdir(app.FramePath);
                end
                BgSize = [ir.height,ir.width];
                d = uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Generating background documents','Cancelable','off','Indeterminate','on');
                ResizeVideo(value,app.FramePath,BgSize);
                close(d);
            elseif strcmp(app.BgMode,'image background')
                % if the background is image,it will be resized and save
                % in cell variable 'bg'
                [file,src_bg] = uigetfile('*.jpg','Select an Background Image');
                OriPath = [src_bg,file];
                app.bg{1} = double(imread(OriPath));
                if [ir.height,ir.width] ~= [size(app.bg{1},1),size(app.bg{1},2)]
                app.bg{1} = imresize(app.bg{1},[ir.height,ir.width]);
                app.FramePath = [src_bg,'resized_bg.jpg'];
                imwrite(app.bg{1}./255,app.FramePath,'jpg');
                msgbox('The size of the background does not match the original video, we have resized the background.','background may look different','warn');
                else
                    app.FramePath = OriPath;
                end  
            end
            app.BgPath = app.FramePath;
            app.BgPathTextArea.Value = app.BgPath;
            
            app.bg = {};
            if strcmp(app.renderMode,'substitute')
                switch app.BgMode
                    case 'image background'  % use still-image background (RGB)
                        % select the path of background
                        app.bg{1} = double(imread(app.BgPath));
                        
                    case 'video background'  % use sequantial background (video)
                        %         VideoPath = uigetdir('*.jpg', 'Select a Background Folder');
                        VideoDir = dir([app.BgPath, filesep, '*.jpg']);
                        app.bg = cell(length(VideoDir), 1);
                        for i = 1:length(VideoDir)
                            app.bg{i} = double(imread(strcat(app.FramePath, filesep, VideoDir(i).name)));
                        end
                end
            end


        end

        % Button pushed function: selectButton_savepath
        function selectButton_savepathPushed(app, event)
            % Select the folder path to save the output.avi and oriVid.avi
            addpath(fullfile('lib'));
            value = uigetdir();
            app.dest = [value,filesep,'output.avi'];
            app.savePath = [value,filesep,'oriVid.avi'];
            app.SavePathTextArea.Value = app.savePath;
        end

        % Value changed function: BgModeDropDown
        function BgModeDropDownValueChanged(app, event)
            % if render_mode is substitute,
            % choice between 'image background' and 'video background'
            value = app.BgModeDropDown.Value;
            app.BgMode = value;
        end

        % Value changed function: RenderModeDropDown
        function RenderModeDropDownValueChanged(app, event)
            % choice between 4 different render_mode
            % details see readme.txt
            value = app.RenderModeDropDown.Value;
            app.renderMode = value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % this function will convert the processed frames and original
            % frames to *.avi
            d = uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Saving output.avi...','Cancelable','off','Indeterminate','off');
            SaveMovie(app.targetFolderMovie,app.dest,app.store);
            d.Value = .45;
            d.Message = 'Removing the Movie frame file...';
            if exist(app.targetFolderMovie,'dir')
                rmdir(app.targetFolderMovie,"s");
            end
            d.Value = .50;
            d.Message = 'Saving orivid.avi...';
            SaveMovie(app.OriFramePath,app.savePath,app.store);
            d.Value = .95;
            if exist(app.OriFramePath,'dir')
                rmdir(app.OriFramePath,"s");
            end
            app.vRender = VideoReader(app.dest);
            app.vOri    = VideoReader(app.savePath);
            d.Value = 1;
            pause(1);
            close(d);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
% this function will play the video

            app.want2stop = false;
            app.vRender = VideoReader(app.dest);
            if app.currentFrame/app.vRender.FrameRate>app.vRender.Duration
                msgbox('End of video');
                app.vRender.CurrentTime = 0;
            else
                app.vRender.CurrentTime = app.currentFrame/app.vRender.FrameRate;
            end
            app.vOri    = VideoReader(app.savePath);
            app.vOri.CurrentTime = app.vRender.CurrentTime;
            app.UIAxes1.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            
            k = 0;
            while hasFrame(app.vRender) 
                if app.want2stop
                    break;
                end
                vidFrameOri = readFrame(app.vOri);
                vidFrameRender = readFrame(app.vRender);
                image(vidFrameOri, 'Parent', app.UIAxes1);
                drawnow;
                if app.want2stop
                    break;
                end
                image(vidFrameRender, 'Parent', app.UIAxes2);
                drawnow;
                k = k+1;
                if app.want2stop
                    break;
                end  
            end
            app.currentFrame = app.currentFrame + k;
            
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
% this funtion will loop play the video
            
            app.want2stop = false;
            app.vRender = VideoReader(app.dest);
            app.currentFrame = 0;
            app.vRender.CurrentTime = 0;
            app.vOri    = VideoReader(app.savePath);
            app.vOri.CurrentTime = app.vRender.CurrentTime;
            app.UIAxes1.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            
            k = 0;
            while true
                if app.want2stop
                    break;
                end
                vidFrameOri = readFrame(app.vOri);
                vidFrameRender = readFrame(app.vRender);
                image(vidFrameOri, 'Parent', app.UIAxes1);
                drawnow;
                image(vidFrameRender, 'Parent', app.UIAxes2);
                drawnow;
                k = k+1;
                if app.want2stop
                    break;
                end
                if app.vOri.CurrentTime >= app.vOri.Duration
                    app.vRender = VideoReader(app.dest);
                    app.currentFrame = 0;
                    app.vRender.CurrentTime = 0;
                    app.vOri    = VideoReader(app.savePath);
                    app.vOri.CurrentTime = app.vRender.CurrentTime;
                end
            end
            app.currentFrame = app.currentFrame + k;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
% this function set property want2stop to true in oder to stop play the video                
            app.want2stop = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.RenderButton = uibutton(app.UIFigure, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Text = 'Render';
            app.RenderModeDropDownLabel = uilabel(app.UIFigure);
            app.RenderModeDropDownLabel.Text = 'RenderMode';
            app.RenderModeDropDown = uidropdown(app.UIFigure);
            app.RenderModeDropDown.Items = {'foreground', 'background', 'overlay', 'substitute'};
            app.RenderModeDropDown.ValueChangedFcn = createCallbackFcn(app, @RenderModeDropDownValueChanged, true);
            app.RenderModeDropDown.Value = 'foreground';
            app.ComputerVisionChallengeG06Label = uilabel(app.UIFigure);
            app.ComputerVisionChallengeG06Label.Text = 'Computer Vision Challenge G06';
            app.StartEditFieldLabel = uilabel(app.UIFigure);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.LDropDownLabel = uilabel(app.UIFigure);
            app.LDropDownLabel.Text = 'L';
            app.LDropDown = uidropdown(app.UIFigure);
            app.LDropDown.Items = {'1', '2'};
            app.LDropDown.Value = '1';
            app.RDropDownLabel = uilabel(app.UIFigure);
            app.RDropDownLabel.Text = 'R';
            app.RDropDown = uidropdown(app.UIFigure);
            app.RDropDown.Items = {'2', '3'};
            app.RDropDown.Value = '2';
            app.selectButton_Src = uibutton(app.UIFigure, 'push');
            app.selectButton_Src.ButtonPushedFcn = createCallbackFcn(app, @selectButton_SrcPushed, true);
            app.selectButton_Src.Text = 'select';
            app.selectButton_BgPath = uibutton(app.UIFigure, 'push');
            app.selectButton_BgPath.ButtonPushedFcn = createCallbackFcn(app, @selectButton_BgPathPushed, true);
            app.selectButton_BgPath.Text = 'select';
            app.BgModeDropDownLabel = uilabel(app.UIFigure);
            app.BgModeDropDownLabel.Text = 'BgMode';
            app.BgModeDropDown = uidropdown(app.UIFigure);
            app.BgModeDropDown.Items = {'    ', 'image background', 'video background'};
            app.BgModeDropDown.ValueChangedFcn = createCallbackFcn(app, @BgModeDropDownValueChanged, true);
            app.BgModeDropDown.Value = '    ';
            app.SourceTextAreaLabel = uilabel(app.UIFigure);
            app.SourceTextAreaLabel.Text = 'Source';
            app.SourceTextArea = uitextarea(app.UIFigure);
            app.BgPathTextAreaLabel = uilabel(app.UIFigure);
            app.BgPathTextAreaLabel.Text = 'BgPath';
            app.BgPathTextArea = uitextarea(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'start.JPG';
            app.Button.Text = '';
            app.selectButton_savepath = uibutton(app.UIFigure, 'push');
            app.selectButton_savepath.ButtonPushedFcn = createCallbackFcn(app, @selectButton_savepathPushed, true);
            app.selectButton_savepath.Text = 'select';
            app.SavePathTextAreaLabel = uilabel(app.UIFigure);
            app.SavePathTextAreaLabel.Text = 'SavePath';
            app.SavePathTextArea = uitextarea(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'stop.jpg';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'loop.jpg';
            app.Button_3.Text = '';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'tum.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = start_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end